classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ControllersListBox        matlab.ui.control.ListBox
        ControllersListBoxLabel   matlab.ui.control.Label
        TestScenariosButtonGroup  matlab.ui.container.ButtonGroup
        TrajectoryTrackingControlButton  matlab.ui.control.RadioButton
        SpeedControlButton        matlab.ui.control.RadioButton
        AutopilotButton           matlab.ui.control.RadioButton
        PLOTButton                matlab.ui.control.Button
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: TestScenariosButtonGroup
        function TestScenariosButtonGroupSelectionChanged(app, event)
            selectedButton = app.TestScenariosButtonGroup.SelectedObject;
            if selectedButton == 1
                clear, clc;
                u0 = 8;             % surge velocity [m/s]
                n_ref = 70;
                n_c = 70;
                setco = 20*pi/180;  % Desired course [degree]

                % Initial state vector
                X = [u0; 0; 0; 0; 0; 0; 0; 0; 0; n_c];
                
                % Control gains for autopilot
                k1 = [2.5; 0.0002; 2];
                
                % Initial values for heading error
                eh0 = 0;
                ehi = 0;
                ehd0 = 0;
                eh = [eh0; ehi; ehd0];
                
                % Simulation time
                tf = 600;
                dt = 0.1;
                index = 0;
                
                % Simulation
                for ii = 0:dt:tf
                    index = index + 1;
                
                    uu = PIDautopilot(k1, eh, n_c);
                    [Xdot, U] = container(X, uu);
                    
                    % Euler's method
                    X = X + dt*Xdot;
                
                    % Boundary of yaw angle
                    if X(6) >= 2*pi
                        X(6) = 2*pi - X(6);
                    elseif X(6) <= -2*pi
                        X(6) = X(6) + 2*pi;
                    else
                        X(6) = X(6);
                    end
                    
                    % Calculate heading error
                    eh(1) = setco - X(6);
                
                    % Set limits for heading error
                    if eh(1) <= -pi
                        eh(1) = eh(1) + 2*pi;
                    elseif eh(1) >= pi
                        eh(1) = eh(1) - 2*pi;
                    else
                        eh(1) = eh(1);
                    end
                
                    % RK2 method
                    k11 = dt*eh(1);
                    k21 = dt*(eh(1) + k11);
                    ehi = ehi + 0.5*(k11 + k21);
                    eh(2) = ehi;
                    ehd = (eh(1) - ehd0)/dt;
                    eh(3) = ehd;
                
                    ehd0 = eh(1);
                
                    % Store data
                    time_pid(index) = ii;   % time [s]
                    data(index,1) = X(1);   % surge velocity [m/s]
                    data(index,2) = X(2);   % sway velocity [m/s]
                    data(index,3) = X(3);   % yaw rate [rad/s]
                    data(index,4) = X(4);   % x-position [m]
                    data(index,5) = X(5);   % y-position [m]
                    data(index,6) = X(6);   % yaw angle [rad]
                    data(index,7) = X(7);   % roll rate [rad/s]
                    data(index,8) = X(8);   % roll angle [rad]
                    data(index,9) = X(9);   % rudder angle [rad]
                    data(index,10) = X(10); % actual shaft velocity [RPM]
                end
                
                % Extract data
                surge_pid = data(:,1);
                sway_pid = data(:,2);
                yaw_rate_pid = data(:,3);
                x_pos_pid = data(:,4);
                y_pos_pid = data(:,5);
                yaw_pid = data(:,6);
                roll_rate_pid = data(:,7);
                roll_pid = data(:,8);
                rudder_pid = data(:,9);
                shaft_pid = data(:,10);
                
                % Load DDPG agent
                load("AutopilotAgent.mat");
                agent = agent1_Trained;
                
                % Main loop for deep reinforcement learning agent
                for ii = 0:dt:tf
                    index = index + 1;
                
                    % Define observation for the RL agent (e.g., current heading error)
                    currentYaw = X(6);  % yaw angle in radians
                    headingError = wrapToPi(setco - currentYaw);  % [-pi, pi]
                    observation = X(9);  
                
                    % Get the rudder action from the RL agent
                    % Convert the observation to a format compatible with the agent
                    action = getAction(agent, observation);
                    action = action{1};
                    
                    % Extract rudder angle command from action output
                    delta_c = action(1);  
                    delta_c = max(min(delta_c, 10*pi/180), -10*pi/180);  
                
                    % Define control input vector [delta_c; n_c]
                    uu = [delta_c; n_c];
                
                    % Update the state using the dynamics function
                    [Xdot, U] = container(X, uu);
                    X = X + dt * Xdot;  % Euler integration for state update
                
                    % Boundary of yaw angle
                    if X(6) >= 2*pi
                        X(6) = X(6) - 2*pi;
                    elseif X(6) <= -2*pi
                        X(6) = X(6) + 2*pi;
                    end
                
                    % Store data
                    data(index,1) = X(1);   % surge velocity [m/s]
                    data(index,2) = X(2);   % sway velocity [m/s]
                    data(index,3) = X(3);   % yaw rate [rad/s]
                    data(index,4) = X(4);   % x-position [m]
                    data(index,5) = X(5);   % y-position [m]
                    data(index,6) = X(6);   % yaw angle [rad]
                    data(index,7) = X(7);   % roll rate [rad/s]
                    data(index,8) = X(8);   % roll angle [rad]
                    data(index,9) = delta_c; % rudder angle command [rad]
                    data(index,10) = X(10); % actual shaft velocity [RPM]
                end
                
                % Extract data for plotting
                surge_drl = data(1:6001,1);
                sway_drl = data(1:6001,2);
                yaw_rate_drl = data(1:6001,3);
                x_pos_drl = data(1:6001,4);
                y_pos_drl = data(1:6001,5);
                yaw_drl = data(1:6001,6);
                roll_rate_drl = data(1:6001,7);
                roll_drl = data(1:6001,8);
                rudder_drl = data(1:6001,9);
                shaft_drl = data(1:6001,10);

            elseif selectedButton == 2
                a = 1
            elseif selectedButton == 3
                a = 2
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            ylabel(app.UIAxes, 'Yaw')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_2, 'Rudder')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes_3, 'Speed')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_4, 'Time [s]')
            ylabel(app.UIAxes_4, 'Shaft')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Trajectory')
            xlabel(app.UIAxes2, 'Y-position')
            ylabel(app.UIAxes2, 'X-position')
            zlabel(app.UIAxes2, 'Z')
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.Text = 'PLOT';
            app.TestScenariosButtonGroup = uibuttongroup(app.UIFigure);
            app.TestScenariosButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TestScenariosButtonGroupSelectionChanged, true);
            app.TestScenariosButtonGroup.Title = 'Test Scenarios';
            app.AutopilotButton = uiradiobutton(app.TestScenariosButtonGroup);
            app.AutopilotButton.Text = 'Autopilot';
            app.AutopilotButton.Value = true;
            app.SpeedControlButton = uiradiobutton(app.TestScenariosButtonGroup);
            app.SpeedControlButton.Text = 'Speed Control';
            app.TrajectoryTrackingControlButton = uiradiobutton(app.TestScenariosButtonGroup);
            app.TrajectoryTrackingControlButton.Text = 'Trajectory Tracking Control';
            app.ControllersListBoxLabel = uilabel(app.UIFigure);
            app.ControllersListBoxLabel.Text = 'Controllers';
            app.ControllersListBox = uilistbox(app.UIFigure);
            app.ControllersListBox.Items = {'PID Controller', 'DDPG Controller'};
            app.ControllersListBox.Value = 'PID Controller';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end